classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        DropDown_2   matlab.ui.control.DropDown
        Label_24     matlab.ui.control.Label
        Image12_2    matlab.ui.control.Image
        Label_23     matlab.ui.control.Label
        Image12      matlab.ui.control.Image
        Image11      matlab.ui.control.Image
        Label_22     matlab.ui.control.Label
        Image10      matlab.ui.control.Image
        DropDown     matlab.ui.control.DropDown
        Label_21     matlab.ui.control.Label
        Label_20     matlab.ui.control.Label
        Label_19     matlab.ui.control.Label
        Image9       matlab.ui.control.Image
        Image8       matlab.ui.control.Image
        EditField_2  matlab.ui.control.NumericEditField
        Label_18     matlab.ui.control.Label
        Image6_3     matlab.ui.control.Image
        ListBox      matlab.ui.control.ListBox
        Label_15     matlab.ui.control.Label
        Slider_3     matlab.ui.control.Slider
        Label_14     matlab.ui.control.Label
        Slider_2     matlab.ui.control.Slider
        Label_13     matlab.ui.control.Label
        Image7_2     matlab.ui.control.Image
        Label_11     matlab.ui.control.Label
        Image6_2     matlab.ui.control.Image
        Label_10     matlab.ui.control.Label
        Image7       matlab.ui.control.Image
        EditField    matlab.ui.control.NumericEditField
        Label_9      matlab.ui.control.Label
        Label_8      matlab.ui.control.Label
        Label_7      matlab.ui.control.Label
        Image6       matlab.ui.control.Image
        Label_6      matlab.ui.control.Label
        Slider       matlab.ui.control.Slider
        Label_5      matlab.ui.control.Label
        Label_4      matlab.ui.control.Label
        Label_3      matlab.ui.control.Label
        Label_2      matlab.ui.control.Label
        Label        matlab.ui.control.Label
        Image5       matlab.ui.control.Image
        Image4       matlab.ui.control.Image
        Image3       matlab.ui.control.Image
        Image2       matlab.ui.control.Image
        ButtonGroup  matlab.ui.container.ButtonGroup
        Button_5     matlab.ui.control.ToggleButton
        Button_4     matlab.ui.control.ToggleButton
        Button_3     matlab.ui.control.ToggleButton
        Button_2     matlab.ui.control.ToggleButton
        Image        matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image
        function ImageClicked(app, event)
            % 打开本地文件选择对话框
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp)'}, ...
                                      '选择一张图片');
            % 检查用户是否选择了文件
            if isequal(file, 0)
                return; % 如果没有选择文件，则退出回调函数
            end
    
            % 构建完整文件路径
            filePath = fullfile(path, file);
    
            % 在 Image 控件中显示选择的图片
            app.Image.ImageSource = filePath;
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            % 检查当前按钮并执行相应操作
            if selectedButton == app.Button_2
                % 灰度化
                if isempty(app.Image.ImageSource)
                    uialert(app.UIFigure, '请先加载图片！', '错误');
                    return;
                end
    
                % 读取图片并转换为灰度图
                img = imread(app.Image.ImageSource);
                grayImg = rgb2gray(img);
    
                % 保存并显示结果
                outputFile = 'gray_result.png';
                imwrite(grayImg, outputFile);
                app.Image2.ImageSource = outputFile;
    
            elseif selectedButton == app.Button_3
                % 直方图显示
                if isempty(app.Image.ImageSource)
                    uialert(app.UIFigure, '请先加载图片！', '错误');
                    return;
                end
    
                % 读取图片并绘制直方图
                img = imread(app.Image.ImageSource);
                grayImg = rgb2gray(img);
                histFile = 'histogram_result.png';
                figure; imhist(grayImg); % 显示直方图
                saveas(gcf, histFile); % 保存为图片
                close; % 关闭临时图像窗口
                app.Image3.ImageSource = histFile;
    
            elseif selectedButton == app.Button_4
                % 直方图均衡化
                if isempty(app.Image.ImageSource)
                    uialert(app.UIFigure, '请先加载图片！', '错误');
                    return;
                end
    
                % 读取图片并均衡化
                img = imread(app.Image.ImageSource);
                grayImg = rgb2gray(img);
                equalizedImg = histeq(grayImg);
                equalizedFile = 'equalized_result.png';
                imwrite(equalizedImg, equalizedFile);
                app.Image4.ImageSource = equalizedFile;
    
            elseif selectedButton == app.Button_5
                % 直方图匹配
                if isempty(app.Image.ImageSource)
                    uialert(app.UIFigure, '请先加载图片！', '错误');
                    return;
                end
    
                % 读取图片并执行直方图匹配
                img = imread(app.Image.ImageSource);
                grayImg = rgb2gray(img);
                reference = uint8(255 * rand(256, 256)); % 随机生成灰度图
                matchedImg = imhistmatch(grayImg, reference);
                matchedFile = 'matched_result.png';
                imwrite(matchedImg, matchedFile);
                app.Image5.ImageSource = matchedFile;
    
            else
                % 未知操作
                uialert(app.UIFigure, '未知操作', '错误');
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            if isempty(app.Image.ImageSource)
                uialert(app.UIFigure, '请先加载图片！', '错误');
                return;
            end
            
            % 读取图片并进行缩放
            img = imread(app.Image.ImageSource);
            scaleValue = value / 100; % 获取滑块的百分比值
            [origHeight, origWidth, numChannels] = size(img); % 原图尺寸
            
            scaledImg = imresize(img, scaleValue); % 缩放图像
            
            % 创建黑色背景，尺寸与原始图像相同
            if numChannels == 3
                background = zeros(origHeight, origWidth, 3, 'uint8'); % RGB 图像
            else
                background = zeros(origHeight, origWidth, 'uint8'); % 灰度图像
            end
        
            % 将缩放后的图像嵌入背景中央
            [scaledHeight, scaledWidth, ~] = size(scaledImg);
            top = floor((origHeight - scaledHeight) / 2) + 1;
            left = floor((origWidth - scaledWidth) / 2) + 1;
            background(top:top + scaledHeight - 1, left:left + scaledWidth - 1, :) = scaledImg;

            % 保存并更新 Image6 显示（使用唯一文件名强制刷新）
            scaledFile = ['scaled_image_', num2str(now, '%.10f'), '.png'];
            imwrite(background, scaledFile);
            app.Image6.ImageSource = scaledFile;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            if isempty(app.Image.ImageSource)
                uialert(app.UIFigure, '请先加载图片！', '错误');
                return;
            end
    
            % 读取图片并进行旋转
            img = imread(app.Image.ImageSource);
            angle = value; % 获取输入的角度值
            rotatedImg = imrotate(img, angle); % 旋转图像
            
            % 保存并更新 Image7 显示（使用唯一文件名强制刷新）
            rotatedFile = ['rotated_image_', num2str(now, '%.10f'), '.png'];
            imwrite(rotatedImg, rotatedFile);
            app.Image7.ImageSource = rotatedFile;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '图像预处理';
            app.Button_2 = uitogglebutton(app.ButtonGroup);
            app.Button_2.Text = '灰度化';
            app.Button_2.Value = true;
            app.Button_3 = uitogglebutton(app.ButtonGroup);
            app.Button_3.Text = '直方图显示';
            app.Button_4 = uitogglebutton(app.ButtonGroup);
            app.Button_4.Text = '直方图均衡化';
            app.Button_5 = uitogglebutton(app.ButtonGroup);
            app.Button_5.Text = '直方图匹配';
            app.Image2 = uiimage(app.UIFigure);
            app.Image3 = uiimage(app.UIFigure);
            app.Image4 = uiimage(app.UIFigure);
            app.Image5 = uiimage(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '灰度图像';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '直方图';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '直方图均衡化';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '直方图匹配';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '缩放比例(%)';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '图像变换';
            app.Image6 = uiimage(app.UIFigure);
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '缩放变换';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '旋转变换';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '旋转角度';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Image7 = uiimage(app.UIFigure);
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '图像增强';
            app.Image6_2 = uiimage(app.UIFigure);
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '线性变换';
            app.Image7_2 = uiimage(app.UIFigure);
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '亮度';
            app.Slider_2 = uislider(app.UIFigure);
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '对比度';
            app.Slider_3 = uislider(app.UIFigure);
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '非线性变换';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'对数变换', '指数变换'};
            app.ListBox.Value = '对数变换';
            app.Image6_3 = uiimage(app.UIFigure);
            app.Label_18 = uilabel(app.UIFigure);
            app.Label_18.Text = '图像加噪';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Image8 = uiimage(app.UIFigure);
            app.Image9 = uiimage(app.UIFigure);
            app.Label_19 = uilabel(app.UIFigure);
            app.Label_19.Text = '空域滤波';
            app.Label_20 = uilabel(app.UIFigure);
            app.Label_20.Text = '频域滤波';
            app.Label_21 = uilabel(app.UIFigure);
            app.Label_21.Text = '边缘检测';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'罗伯特算子', 'Prewitt算子', 'Sobel算子', '拉普拉斯算子'};
            app.DropDown.Value = '罗伯特算子';
            app.Image10 = uiimage(app.UIFigure);
            app.Label_22 = uilabel(app.UIFigure);
            app.Label_22.Text = '目标提取';
            app.Image11 = uiimage(app.UIFigure);
            app.Image12 = uiimage(app.UIFigure);
            app.Label_23 = uilabel(app.UIFigure);
            app.Label_23.Text = '特征提取';
            app.Image12_2 = uiimage(app.UIFigure);
            app.Label_24 = uilabel(app.UIFigure);
            app.Label_24.Text = '图像分类';
            app.DropDown_2 = uidropdown(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end